classdef TwoSpecies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Two_Species            matlab.ui.Figure
        CalButton              matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        tMaxEditField          matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        stepLengthEditField_2  matlab.ui.control.NumericEditField
        timegraph              matlab.ui.control.UIAxes
        xygraph                matlab.ui.control.UIAxes
        s1SpinnerLabel         matlab.ui.control.Label
        s1EditField            matlab.ui.control.Spinner
        s2SpinnerLabel         matlab.ui.control.Label
        s2EditField            matlab.ui.control.Spinner
        n2SpinnerLabel         matlab.ui.control.Label
        n2EditField            matlab.ui.control.Spinner
        n1Label                matlab.ui.control.Label
        n1EditField            matlab.ui.control.Spinner
        r1Label                matlab.ui.control.Label
        r1EditField            matlab.ui.control.Spinner
        r2Label                matlab.ui.control.Label
        r2EditField            matlab.ui.control.Spinner
        x0SpinnerLabel         matlab.ui.control.Label
        x0EditField            matlab.ui.control.Spinner
        y0Label                matlab.ui.control.Label
        y0EditField            matlab.ui.control.Spinner
        WenhaoXieLabel         matlab.ui.control.Label
        LockAxes               matlab.ui.control.CheckBox
        xMaxEditFieldLabel     matlab.ui.control.Label
        xMaxEditField          matlab.ui.control.NumericEditField
        yMaxEditFieldLabel     matlab.ui.control.Label
        yMaxEditField          matlab.ui.control.NumericEditField
        LockAxes_2             matlab.ui.control.CheckBox
        xMaxEditField_2Label   matlab.ui.control.Label
        xMaxEditField_2        matlab.ui.control.NumericEditField
        yMaxEditField_2Label   matlab.ui.control.Label
        yMaxEditField_2        matlab.ui.control.NumericEditField
        Reset                  matlab.ui.control.Button
        ResultTime             matlab.ui.control.Label
        rstep                  matlab.ui.control.DropDown
        nstep                  matlab.ui.control.DropDown
        sstep                  matlab.ui.control.DropDown
        xy0step                matlab.ui.control.DropDown
        DieOutSpeciesMax       matlab.ui.control.Label
        Help                   matlab.ui.control.Button
        Grid1                  matlab.ui.control.CheckBox
        Grid2                  matlab.ui.control.CheckBox
        Output                 matlab.ui.control.Button
        Output_2               matlab.ui.control.Button
    end

    
    properties (Access = private)
        outputArrays % Description
        tArray % Description
        Lock   % Description
        Lock2  % Description
    end


    methods (Access = private)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            app.Lock = 1;
            app.Lock2 = 1;
            app.xMaxEditField_2.Editable=0;
            app.yMaxEditField_2.Editable=0;
            app.xMaxEditField.Editable=0;
            app.yMaxEditField.Editable=0;
        end

        % Callback function: CalButton, n1EditField, n1EditField, 
        % n2EditField, n2EditField, r1EditField, r1EditField, 
        % r2EditField, r2EditField, s1EditField, s1EditField, 
        % s2EditField, s2EditField, stepLengthEditField_2, 
        % tMaxEditField, x0EditField, x0EditField, xMaxEditField, 
        % xMaxEditField_2, y0EditField, y0EditField, yMaxEditField, 
        % yMaxEditField_2
        function CalButtonPushed(app, event)
            r1=app.r1EditField.Value;
            r2=app.r2EditField.Value;
            n1=app.n1EditField.Value;
            n2=app.n2EditField.Value;
            s1=app.s1EditField.Value;
            s2=app.s2EditField.Value;
            x0=app.x0EditField.Value;
            y0=app.y0EditField.Value;
            func=@(x,y,t) [r1*x*(1-x/n1-s1*y/n2),r2*y*(1-s2*x/n1-y/n2)];
            app.tArray=0:0.01:app.tMaxEditField.Value;
            InitVal=[x0,y0];
            tInitVal=0;
            stepLength=app.stepLengthEditField_2.Value;
            n=length(app.tArray);
            app.outputArrays=zeros(2,n);
            Xmax=max(app.tArray);
            stepCount=ceil((Xmax-tInitVal)/stepLength);
            Result=zeros(2,stepCount);
            % Result(1,i) is x when t=i*stepLength + tInitVal
            % Result(2,i) is y when t=i*stepLength + tInitVal
            temp=func(InitVal(1),InitVal(2),tInitVal);
            Result(1,1)=InitVal(1)+temp(1)*stepLength;
            Result(2,1)=InitVal(2)+temp(2)*stepLength;
            for i=1:stepCount
                tempk1=func(Result(1,i),Result(2,i),i*stepLength+tInitVal);
                tempk2=func(Result(1,i)+1/2*stepLength*tempk1(1),Result(2,i)+1/2*stepLength*tempk1(2),(i+1/2)*stepLength+tInitVal);
                tempk3=func(Result(1,i)+1/2*stepLength*tempk2(1),Result(2,i)+1/2*stepLength*tempk2(2),(i+1/2)*stepLength+tInitVal);
                tempk4=func(Result(1,i)+stepLength*tempk3(1),Result(2,i)+stepLength*tempk3(2),(i+1)*stepLength+tInitVal);
                Result(1,i+1)=(tempk1(1)+2*tempk2(1)+2*tempk3(1)+tempk4(1))*stepLength/6+Result(1,i);
                Result(2,i+1)=(tempk1(2)+2*tempk2(2)+2*tempk3(2)+tempk4(2))*stepLength/6+Result(2,i);
            end
            ResultTimeCalculated_Flag=0;
            DieOutSpecies_Flag=0;%1表示x种群灭绝，2表示y种群灭绝
            for j=1:n
                p=(app.tArray(j)-tInitVal)/stepLength;
                rate=p-floor(p);
                if p>=1
                    app.outputArrays(:,j)=(1-rate)*Result(:,floor(p))+rate*Result(:,floor(p)+1);
                else
                    app.outputArrays(:,j)=(1-rate)*InitVal(:)+rate*Result(:,1);
                end
                if ResultTimeCalculated_Flag==0 && (app.outputArrays(1,j)>0.99*n1 || app.outputArrays(2,j)>0.99*n2)
                    app.ResultTime.Text=sprintf('达到平衡(99%%)时间：%7.2f',j*0.01);
                    ResultTimeCalculated_Flag=1;
                    if app.outputArrays(1,j)>app.outputArrays(2,j)
                        DieOutSpecies_Flag=2;%y种群灭绝
                    else
                        DieOutSpecies_Flag=1;%x种群灭绝
                    end
                end
            end
            if DieOutSpecies_Flag==2
                app.DieOutSpeciesMax.Text=sprintf('灭绝种群最大数量：%7.2f',max(app.outputArrays(2,:)));
            elseif DieOutSpecies_Flag==1
                app.DieOutSpeciesMax.Text=sprintf('灭绝种群最大数量：%7.2f',max(app.outputArrays(1,:)));
            end
            if app.Lock==0 && app.yMaxEditField.Value>0 && app.xMaxEditField.Value>0
                ylim(app.timegraph,[0 app.yMaxEditField.Value]);
                xlim(app.timegraph,[0 app.xMaxEditField.Value]);
            elseif app.Lock==1
                ylim(app.timegraph,[0 inf]);
                xlim(app.timegraph,[0 inf]);
            end
            
            plot(app.timegraph, app.tArray, app.outputArrays(1,:),app.tArray, app.outputArrays(2,:))
            legend(app.timegraph,'甲种群数量','乙种群数量')
            
            if app.Lock2==0 && app.yMaxEditField_2.Value>0 && app.xMaxEditField_2.Value>0
                ylim(app.xygraph,[0 app.yMaxEditField_2.Value])
                xlim(app.xygraph,[0 app.xMaxEditField_2.Value])
            elseif app.Lock2==1
                ylim(app.xygraph,[0 inf])
                xlim(app.xygraph,[0 inf])
            end
            plot(app.xygraph,app.outputArrays(1,:),app.outputArrays(2,:))
            
        end

        % Value changed function: LockAxes
        function LockAxesValueChanged(app, event)
            app.Lock = app.LockAxes.Value;
            if app.Lock==1
                app.xMaxEditField.Editable=0;
                app.yMaxEditField.Editable=0;
            elseif app.Lock==0
                app.xMaxEditField.Editable=1;
                app.yMaxEditField.Editable=1;
            end
            CalButtonPushed(app,event);
        end

        % Value changed function: LockAxes_2
        function LockAxes_2ValueChanged(app, event)
            app.Lock2 = app.LockAxes_2.Value;
            if app.Lock2==1
                app.xMaxEditField_2.Editable=0;
                app.yMaxEditField_2.Editable=0;
            elseif app.Lock2==0
                app.xMaxEditField_2.Editable=1;
                app.yMaxEditField_2.Editable=1;
            end
            CalButtonPushed(app,event);
        end

        % Button pushed function: Reset
        function ResetPushed(app, event)
            app.r1EditField.Value=1;
            app.r2EditField.Value=1;
            app.n1EditField.Value=100;
            app.n2EditField.Value=100;
            app.s1EditField.Value=0.5;
            app.s2EditField.Value=2;
            app.x0EditField.Value=10;
            app.y0EditField.Value=10;
            app.tMaxEditField.Value=15;
            app.stepLengthEditField_2.Value=0.01;
        end

        % Value changed function: rstep
        function rstepValueChanged(app, event)
            app.r1EditField.Step = str2double(app.rstep.Value);
            app.r2EditField.Step = str2double(app.rstep.Value);
        end

        % Value changed function: nstep
        function nstepValueChanged(app, event)
            app.n1EditField.Step = str2double(app.nstep.Value);
            app.n2EditField.Step = str2double(app.nstep.Value);
        end

        % Value changed function: sstep
        function sstepValueChanged(app, event)
            app.s1EditField.Step = str2double(app.sstep.Value);
            app.s2EditField.Step = str2double(app.sstep.Value);
        end

        % Value changed function: xy0step
        function xy0stepValueChanged(app, event)
            app.x0EditField.Step = str2double(app.xy0step.Value);
            app.y0EditField.Step = str2double(app.xy0step.Value);
        end

        % Button pushed function: Help
        function HelpPushed(app, event)
            msgbox({'1.左侧的种群参数列表可以对参数进行修改；上下的小箭头可以按照右侧指定的步长进行增减调节。'
                    '2.导出功能会弹出一个figure图窗，请自行保存或作其他修改。'
                    '软件作者：Wenhao Xie'
                    '软件版本：V1.1'},'帮助');
        end

        % Value changed function: Grid1
        function Grid1ValueChanged(app, event)
            value = app.Grid1.Value;
            if value==1
                app.timegraph.XGrid='on';
                app.timegraph.YGrid='on';
                app.timegraph.XMinorGrid='on';
            elseif value==0
                app.timegraph.XGrid='off';
                app.timegraph.YGrid='off';
                app.timegraph.XMinorGrid='off';
            end
        end

        % Value changed function: Grid2
        function Grid2ValueChanged(app, event)
            value = app.Grid2.Value;
            if value==1
                app.xygraph.XGrid='on';
                app.xygraph.YGrid='on';
            elseif value==0
                app.xygraph.XGrid='off';
                app.xygraph.YGrid='off';
            end
        end

        % Button pushed function: Output
        function OutputButtonPushed(app, event)
            figure(1)            
            plot(app.tArray, app.outputArrays(1,:),app.tArray, app.outputArrays(2,:))
            grid on
            legend('甲种群数量','乙种群数量'),title('种群数量随时间变化的曲线'),xlabel('时间t'),ylabel('种群数量')
            if app.Grid1.Value==1
                grid on
            elseif app.Grid1.Value==0
                grid off
            end
            if app.Lock==0
                axis([0 app.xMaxEditField.Value 0 app.yMaxEditField.Value]);
            end
        end

        % Button pushed function: Output_2
        function Output_2ButtonPushed(app, event)
            figure(2)
            plot(app.outputArrays(1,:),app.outputArrays(2,:))
            title('种群数量相图'),xlabel('甲种群数量'),ylabel('乙种群数量')
            grid on
            if app.Grid2.Value==1
                grid on
            elseif app.Grid2.Value==0
                grid off
            end
            if app.Lock2==0
                axis([0 app.xMaxEditField_2.Value 0 app.yMaxEditField_2.Value]);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Two_Species = uifigure;
            app.Two_Species.Name = '两种群竞争模型';
            app.CalButton = uibutton(app.Two_Species, 'push');
            app.CalButton.ButtonPushedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.CalButton.Text = '计算';
            app.Label = uilabel(app.Two_Species);
            app.Label.Text = '种群参数        值         调节步长';
            app.Label_2 = uilabel(app.Two_Species);
            app.Label_2.Text = '终止时间';
            app.tMaxEditField = uieditfield(app.Two_Species, 'numeric');
            app.tMaxEditField.Limits = [0 Inf];
            app.tMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.tMaxEditField.Value = 15;
            app.Label_3 = uilabel(app.Two_Species);
            app.Label_3.Text = '微分步长';
            app.stepLengthEditField_2 = uieditfield(app.Two_Species, 'numeric');
            app.stepLengthEditField_2.Limits = [0 Inf];
            app.stepLengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.stepLengthEditField_2.Value = 0.01;
            app.timegraph = uiaxes(app.Two_Species);
            title(app.timegraph, '种群数量随时间变化的曲线')
            xlabel(app.timegraph, '时间t')
            ylabel(app.timegraph, '种群数量')
            app.xygraph = uiaxes(app.Two_Species);
            title(app.xygraph, '种群数量相图')
            xlabel(app.xygraph, '甲种群数量')
            ylabel(app.xygraph, '乙种群数量')
            app.s1SpinnerLabel = uilabel(app.Two_Species);
            app.s1SpinnerLabel.Text = 's1';
            app.s1EditField = uispinner(app.Two_Species);
            app.s1EditField.Step = 0.1;
            app.s1EditField.ValueChangingFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.s1EditField.Limits = [0 Inf];
            app.s1EditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.s1EditField.Value = 0.5;
            app.s2SpinnerLabel = uilabel(app.Two_Species);
            app.s2SpinnerLabel.Text = 's2';
            app.s2EditField = uispinner(app.Two_Species);
            app.s2EditField.Step = 0.1;
            app.s2EditField.ValueChangingFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.s2EditField.Limits = [0 Inf];
            app.s2EditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.s2EditField.Value = 2;
            app.n2SpinnerLabel = uilabel(app.Two_Species);
            app.n2SpinnerLabel.Text = 'n2';
            app.n2EditField = uispinner(app.Two_Species);
            app.n2EditField.ValueChangingFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.n2EditField.Limits = [0 Inf];
            app.n2EditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.n2EditField.Value = 100;
            app.n1Label = uilabel(app.Two_Species);
            app.n1Label.Text = 'n1';
            app.n1EditField = uispinner(app.Two_Species);
            app.n1EditField.ValueChangingFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.n1EditField.Limits = [0 Inf];
            app.n1EditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.n1EditField.Value = 100;
            app.r1Label = uilabel(app.Two_Species);
            app.r1Label.Text = 'r1';
            app.r1EditField = uispinner(app.Two_Species);
            app.r1EditField.Step = 0.1;
            app.r1EditField.ValueChangingFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.r1EditField.Limits = [0 Inf];
            app.r1EditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.r1EditField.Value = 1;
            app.r2Label = uilabel(app.Two_Species);
            app.r2Label.Text = 'r2';
            app.r2EditField = uispinner(app.Two_Species);
            app.r2EditField.Step = 0.1;
            app.r2EditField.ValueChangingFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.r2EditField.Limits = [0 Inf];
            app.r2EditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.r2EditField.Value = 1;
            app.x0SpinnerLabel = uilabel(app.Two_Species);
            app.x0SpinnerLabel.Text = 'x0';
            app.x0EditField = uispinner(app.Two_Species);
            app.x0EditField.ValueChangingFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.x0EditField.Limits = [0 Inf];
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.x0EditField.Value = 10;
            app.y0Label = uilabel(app.Two_Species);
            app.y0Label.Text = 'y0';
            app.y0EditField = uispinner(app.Two_Species);
            app.y0EditField.ValueChangingFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.y0EditField.Limits = [0 Inf];
            app.y0EditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.y0EditField.Value = 10;
            app.WenhaoXieLabel = uilabel(app.Two_Species);
            app.WenhaoXieLabel.Text = '作者：Wenhao Xie';
            app.LockAxes = uicheckbox(app.Two_Species);
            app.LockAxes.ValueChangedFcn = createCallbackFcn(app, @LockAxesValueChanged, true);
            app.LockAxes.Text = '坐标轴自动变化';
            app.LockAxes.Value = true;
            app.xMaxEditFieldLabel = uilabel(app.Two_Species);
            app.xMaxEditFieldLabel.Text = 'xMax';
            app.xMaxEditField = uieditfield(app.Two_Species, 'numeric');
            app.xMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.xMaxEditField.Value = 15;
            app.yMaxEditFieldLabel = uilabel(app.Two_Species);
            app.yMaxEditFieldLabel.Text = 'yMax';
            app.yMaxEditField = uieditfield(app.Two_Species, 'numeric');
            app.yMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.yMaxEditField.Value = 100;
            app.LockAxes_2 = uicheckbox(app.Two_Species);
            app.LockAxes_2.ValueChangedFcn = createCallbackFcn(app, @LockAxes_2ValueChanged, true);
            app.LockAxes_2.Text = '坐标轴自动变化';
            app.LockAxes_2.Value = true;
            app.xMaxEditField_2Label = uilabel(app.Two_Species);
            app.xMaxEditField_2Label.Text = 'xMax';
            app.xMaxEditField_2 = uieditfield(app.Two_Species, 'numeric');
            app.xMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.xMaxEditField_2.Value = 100;
            app.yMaxEditField_2Label = uilabel(app.Two_Species);
            app.yMaxEditField_2Label.Text = 'yMax';
            app.yMaxEditField_2 = uieditfield(app.Two_Species, 'numeric');
            app.yMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.yMaxEditField_2.Value = 100;
            app.Reset = uibutton(app.Two_Species, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetPushed, true);
            app.Reset.Text = '重置';
            app.ResultTime = uilabel(app.Two_Species);
            app.ResultTime.Text = '达到平衡(99%)时间：       ';
            app.rstep = uidropdown(app.Two_Species);
            app.rstep.Items = {'0.1', '0.2', '0.5', '1'};
            app.rstep.ItemsData = {'0.1', '0.2', '0.5', '1'};
            app.rstep.ValueChangedFcn = createCallbackFcn(app, @rstepValueChanged, true);
            app.rstep.Value = '0.1';
            app.nstep = uidropdown(app.Two_Species);
            app.nstep.Items = {'1', '2', '5', '10'};
            app.nstep.ItemsData = {'1', '2', '5', '10'};
            app.nstep.ValueChangedFcn = createCallbackFcn(app, @nstepValueChanged, true);
            app.nstep.Value = '1';
            app.sstep = uidropdown(app.Two_Species);
            app.sstep.Items = {'0.1', '0.2', '0.5', '1'};
            app.sstep.ItemsData = {'0.1', '0.2', '0.5', '1'};
            app.sstep.ValueChangedFcn = createCallbackFcn(app, @sstepValueChanged, true);
            app.sstep.Value = '0.1';
            app.xy0step = uidropdown(app.Two_Species);
            app.xy0step.Items = {'1', '2', '5', '10'};
            app.xy0step.ItemsData = {'1', '2', '5', '10'};
            app.xy0step.ValueChangedFcn = createCallbackFcn(app, @xy0stepValueChanged, true);
            app.xy0step.Value = '1';
            app.DieOutSpeciesMax = uilabel(app.Two_Species);
            app.DieOutSpeciesMax.Text = '灭绝种群最大数量：       ';
            app.Help = uibutton(app.Two_Species, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpPushed, true);
            app.Help.Text = '帮助';
            app.Grid1 = uicheckbox(app.Two_Species);
            app.Grid1.ValueChangedFcn = createCallbackFcn(app, @Grid1ValueChanged, true);
            app.Grid1.Text = '网格线';
            app.Grid2 = uicheckbox(app.Two_Species);
            app.Grid2.ValueChangedFcn = createCallbackFcn(app, @Grid2ValueChanged, true);
            app.Grid2.Text = '网格线';
            app.Output = uibutton(app.Two_Species, 'push');
            app.Output.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.Output.Text = '导出';
            app.Output_2 = uibutton(app.Two_Species, 'push');
            app.Output_2.ButtonPushedFcn = createCallbackFcn(app, @Output_2ButtonPushed, true);
            app.Output_2.Text = '导出';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TwoSpecies

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Two_Species)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Two_Species)
        end
    end
end